classdef Bondpy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        ImportdataMenu         matlab.ui.container.Menu
        ParametersPanel        matlab.ui.container.Panel
        MinEditFieldLabel      matlab.ui.control.Label
        MinEditField           matlab.ui.control.NumericEditField
        MaxEditFieldLabel      matlab.ui.control.Label
        MaxEditField           matlab.ui.control.NumericEditField
        CalculateButton        matlab.ui.control.Button
        FundNameDropDownLabel  matlab.ui.control.Label
        FundNameDropDown       matlab.ui.control.DropDown
        UITable                matlab.ui.control.Table
    end


    properties (Access = private)
        mink % minimun price
        maxk % Maximum price
        StockName % Fund name
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.mink = app.MinEditField.Value;
             app.maxk = app.MaxEditField.Value;
             app.StockName = app.FundnameEditField.Value;           
        end

        % Menu selected function: ImportdataMenu
        function ImportdataMenuSelected(app, event)
            [filename, pathname] = uigetfile('*.csv', 'Load data: BondData');
            app.data = readtable([pathname filename]);
        end

        % Callback function: CalculateButton, UITable
        function CalculateButtonPushed(app, event)
            app.data.Security = categorical(app.data.Security); % convert cell to categorical
            idx = ~isundefined(app.data.Security); % get indices for defined Funds
            app.data = app.data(idx,:); % use logical indexing to remove all the totals
            app.data.Pfolio = categorical(app.data.Pfolio); % convert to categorical
            app.data = sortrows(app.data, 'SectorLevel0');
            %**************************************************************************%
            %Next steps is to sort the table by sector levels
            %I need portfolio list used frequenntly
            
            %% *Isolate all the portfolios with a 0 -1 years sector level *
            
            app.data.SectorLevel0 = categorical(app.data.SectorLevel0); % Convert to cats
            SectorLevel= app.data.SectorLevel0; % Isolating sector level data
            
            SectorLevelCell = categories(SectorLevel);
            Zero_One = SectorLevelCell(1:3)';
            
            Zero_One = categorical(Zero_One);
            idx1 = app.data.SectorLevel0 == Zero_One;
            idx2 = any(idx1, 2);
            Port01 = app.data(idx2,:);
            port = Port01.Pfolio == portname;
            port = Port01(port, :);
            port = sortrows(port, 'NominalValue', 'descend');
            
            % Sum 1 to 1, 1:2, until 1:17
            % example: sum(data(1:4))
            T = cumsum(port.NominalValue(1:length(port.NominalValue)));
            
            V = T>app.mink & T<app.maxk;
            port.Security(V);
            T(V);
            ix = find(V);
            %Extract the stocks that give you 
                for k = 1:nnz(ix)
                    app.UITable.StockName = port(1:ix(k), {'Security', 'NominalValue', 'MarketValue'});
                end
            
            app.total = sum(app.StockName.MarketValue);
            %
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdataMenuSelected, true);
            app.ImportdataMenu.Text = 'Import data';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MinEditFieldLabel = uilabel(app.ParametersPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.ParametersPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CalculateButton = uibutton(app.ParametersPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FundNameDropDownLabel = uilabel(app.ParametersPanel);
            app.FundNameDropDownLabel.Text = 'Fund Name';
            app.FundNameDropDown = uidropdown(app.ParametersPanel);
            app.FundNameDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9', 'Option 10', 'Option 11', 'Option 12', 'Option 13', 'Option 14', 'Option 15', 'Option 16', 'Option 17', 'Option 18', 'Option 19', 'Option 20', 'Option 21', 'Option 22', 'Option 23', 'Option 24', 'Option 25', 'Option 26', 'Option 27', 'Option 28', 'Option 29', 'Option 30', 'Option 31', 'Option 32', 'Option 33', 'Option 34', 'Option 35', 'Option 36', 'Option 37', 'Option 38', 'Option 39', 'Option 40', 'Option 41', 'Option 42', 'Option 43', 'Option 44', 'Option 45', 'Option 46', 'Option 47', 'Option 48', 'Option 49', 'Option 50', 'Option 51', 'Option 52', 'Option 53', 'Option 54', 'Option 55', 'Option 56', 'Option 57', 'Option 58', 'Option 59', 'Option 60', 'Option 61', 'Option 62', 'Option 63', 'Option 64', 'Option 65', 'Option 66', 'Option 67', 'Option 68', 'Option 69', 'Option 70', 'Option 71', 'Option 72', 'Option 73', 'Option 74', 'Option 75', 'Option 76', 'Option 77', 'Option 78', 'Option 79', 'Option 80', 'Option 81', 'Option 82', 'Option 83', 'Option 84', 'Option 85', 'Option 86', 'Option 87', 'Option 88', 'Option 89', 'Option 90', 'Option 91', 'Option 92', 'Option 93', 'Option 94', 'Option 95', 'Option 96', 'Option 97', 'Option 98', 'Option 99', 'Option 100', 'Option 101', 'Option 102', 'Option 103', 'Option 104', 'Option 105', 'Option 106', 'Option 107', 'Option 108', 'Option 109', 'Option 110', 'Option 111', 'Option 112', 'Option 113', 'Option 114', 'Option 115', 'Option 116', 'Option 117', 'Option 118', 'Option 119', 'Option 120', 'Option 121', 'Option 122', 'Option 123', 'Option 124', 'Option 125'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Security'; 'Nominal Value'; 'Market Value'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @CalculateButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bondpy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end